classdef Face_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        screen             matlab.ui.Figure
        RestorationPanel   matlab.ui.container.Panel
        restoration        matlab.ui.control.Button
        ThresholdingPanel  matlab.ui.container.Panel
        thresholding       matlab.ui.control.Button
        save               matlab.ui.control.Button
        EdgePanel          matlab.ui.container.Panel
        sobel              matlab.ui.control.Button
        canny              matlab.ui.control.Button
        uibuttongroup3_3   matlab.ui.container.ButtonGroup
        result             matlab.ui.control.UIAxes
        uibuttongroup3_2   matlab.ui.container.ButtonGroup
        profile            matlab.ui.control.UIAxes
        CAMERAPanel        matlab.ui.container.Panel
        close              matlab.ui.control.Button
        spot               matlab.ui.control.Button
        camera             matlab.ui.control.Button
        TOLPanel           matlab.ui.container.Panel
        variance           matlab.ui.control.Button
        ENHANCEPanel       matlab.ui.container.Panel
        histeq             matlab.ui.control.Button
        smooth             matlab.ui.control.Button
        sharpen            matlab.ui.control.Button
        lowpass            matlab.ui.control.Button
        Upload             matlab.ui.control.Hyperlink
        uibuttongroup3     matlab.ui.container.ButtonGroup
        match              matlab.ui.control.Button
        chooseButton       matlab.ui.control.Button
        load               matlab.ui.control.Button
        edge               matlab.ui.control.UIAxes
        edge1              matlab.ui.control.UIAxes
        histE              matlab.ui.control.UIAxes
        hist               matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function face_OpeningFcn(app, varargin)
            movegui(app.screen, 'onscreen');
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);
            handles.output = hObject;
            COP=axes('units','normalized','pos',[0 0 1 1]);
            uistack(COP,'bottom');  
            BGIMG=imread('bg.jpg');   
            image(BGIMG);
            colormap gray
            set(COP,'handlevisibility','off','visible','off');
            guidata(hObject, handles);
            
        end

        % Button pushed function: chooseButton
        function chooseButton_Callback(app, event)
            global PHOTO;
            % load image to be recognize          
            [filename, ABS_PATH] = uigetfile({'*.*'},'choose photo');
            str = [ABS_PATH, filename];
            PHOTO = imread(str);
%             delete(str);
            imwrite(PHOTO,'__CACHE__.jpg','jpg');        
            PHOTO=imresize(im2gray(imread('__CACHE__.jpg')),[480,480]);
            delete('__CACHE__.jpg');
%             whos PHOTO
%             imwrite(PHOTO,'__CACHE__.jpg','jpg');

            set(app.histeq,'Enable','on');
            set(app.lowpass,'Enable','on');
            set(app.smooth,'Enable','on');
            set(app.sharpen,'Enable','on');

            set(app.restoration,'Enable','on');
            set(app.thresholding,'Enable','off');

            set(app.match,'Enable','on');
            set(app.save,'Enable','on');
            set(app.variance,'Enable','off');

            set(app.sobel,'Enable','off');
            set(app.canny,'Enable','off');
            
            axes(app.profile);
            imshow(PHOTO);

            axes(app.hist); 
            histogram(PHOTO);

            axes(app.histE); 
            histogram(histeq(PHOTO));
%             imshow(edge(PHOTO,'sobel',0.1,'nothinning'));


        end

        % Button pushed function: match
        function match_Callback(app, event)
            global PHOTO_Edge PHOTO_Edge1 PHOTO GLOBAL_SPACE Wpca PHOTO_MEAN DATA_COLUMN ABS_PATH PATH_LIST DISTANCE

            PHOTO_Edge=PHOTO;
            PHOTO = double(PHOTO(:)); 
            
            PHOTO_CENT = PHOTO(:,size(PHOTO,2)) - PHOTO_MEAN;
            PHOTO_SPACE = Wpca'*(PHOTO_CENT);
            DISTANCE = Inf;     
            % Match with Minimum Distance Method
            for i = 1:DATA_COLUMN
                TMP = norm(PHOTO_SPACE - GLOBAL_SPACE(:,i));
                if(DISTANCE-TMP>0)
                    DISTANCE = TMP;
                    TRARGET_PATH = strcat(ABS_PATH, '/', PATH_LIST(i).name);
                    axes( app.result )
                    pause(0.00001);
                    imshow(imread(TRARGET_PATH));                    
                end
            end
            
            if(DISTANCE<4.0e+07)              
                msgbox('Find perfect match!','Matched','help');            
            elseif (DISTANCE<4.5e+8)
                msgbox('Find similar match!','Warning','warn');     
            else
                msgbox('Did not find a match!','Error','error'); 
            end

            PHOTO_Edge1=imread(TRARGET_PATH);

            set(app.variance,'Enable','on');

            set(app.histeq,'Enable','off');
            set(app.lowpass,'Enable','off');
            set(app.smooth,'Enable','off');
            set(app.sharpen,'Enable','off');

            set(app.restoration,'Enable','off');
            set(app.thresholding,'Enable','on');
            
            set(app.match,'Enable','off');
           
            set(app.sobel,'Enable','on');
            set(app.canny,'Enable','on');

        end

        % Button pushed function: load
        function load_Callback(app, event)
            global GLOBAL_SPACE ABS_PATH PHOTO_MEAN DATA_COLUMN PATH_LIST Wpca
            % Load photos
            ABS_PATH = uigetdir;
            PATH_LIST = dir(strcat(ABS_PATH,'\*.jpg'));
            IMG_Data = [];              
            if (~isempty(PATH_LIST))
                for i = 1:length(PATH_LIST)
                    TMP = imread(strcat(ABS_PATH, '/', PATH_LIST(i).name));
                    TMP = double(TMP(:));
                    IMG_Data = [IMG_Data,TMP];
                end
            end
            
            DATA_COLUMN = size(IMG_Data,2);      
            PHOTO_MEAN = mean(IMG_Data,2); 
            
            % Calculate centered convariance matrix
            for i = 1:DATA_COLUMN
                IMG_Data(:,i) = IMG_Data(:,i) - PHOTO_MEAN;   
            end
            [COE, ~, EXP] = pcacov(IMG_Data'*IMG_Data);

            % Choose the largest K eigenvalues
            i = 1;
            prop = 0;
            while(prop < 90)
                prop = prop + EXP(i);
                i = i+1;
            end
            k = i-1;
            
            % Calculate the optimal transformation matrix
            Wpca = IMG_Data*COE; 
            Wpca = Wpca(:,1:k);
            whos Wpca;
            
            % Project the centered data into the subspace
            GLOBAL_SPACE = Wpca'*IMG_Data;
            h=msgbox('Image library loaded!','Data','help');
            pause(1);
            delete(h);

        end

        % Button pushed function: variance
        function varianceButtonPushed(app, event)
            global DISTANCE
            msgbox(['The variance is: ',num2str(DISTANCE, '%.2e')],'Variance')

        end

        % Button pushed function: camera
        function cameraPushed(app, event)
            global vid
            set(app.camera,'Enable','off');
            vid = videoinput('winvideo',1, 'YUY2_640x480');

            set(vid,'FramesPerTrigger',1);
  
            set(vid,'TriggerRepeat',Inf);
            set(vid,'ReturnedColorSpace','grayscale');
            set(app.spot,'Enable','on');
            set(app.close,'Enable','on');
            preview(vid);
                        
        end

        % Button pushed function: spot
        function spotPushed(app, event)
            global vid;
            display(vid);
%             vid = videoinput('winvideo', 1,'YUY2_640x480');
            
                vidRes = get(vid, 'VideoResolution');
                nBands = get(vid, 'NumberOfBands');
    %             hImage = image( zeros(vidRes(2), vidRes(1), nBands) );
                %white = image( ones(vidRes(2), vidRes(1), nBands) );
                %preview(source.vid, hImage);
                a=getsnapshot(vid);
                % b=getsnapshot(source.vid);
                % c=a-b;
                a=imcrop(a, [80, 0, 479, 480]);
                imwrite(a,'new.jpg','jpg');
                h=msgbox('Thank you!','Photo','help');
                pause(0.5);
                close(h);
            
        end

        % Button pushed function: close
        function closePushed(app, event)
            global vid
            delete(vid);
            set(app.spot,'Enable','off');
            set(app.close,'Enable','off');
            set(app.camera,'Enable','on');
            
        end

        % Button pushed function: smooth
        function smoothButtonPushed(app, event)
            global PHOTO         

%             PHOTO=imfilter(PHOTO,fspecial('average',[3,3]));

            PHOTO=medfilt2(PHOTO);
            axes( app.profile); 
            imshow(PHOTO);
            h=msgbox('Smoothed','Filter','help');
            pause(0.5);
            close(h);
            
        end

        % Button pushed function: histeq
        function histeqButtonPushed(app, event)
            global PHOTO
            PHOTO=histeq(PHOTO);
            axes( app.profile )
            imshow(PHOTO)
            h=msgbox('Equaled','Filter','help');
            pause(0.5);
            close(h);

        end

        % Button pushed function: lowpass
        function lowpassButtonPushed(app, event)
            global PHOTO
            d0=50; 
            image=PHOTO;
            [M ,N]=size(image);
            
            img_f = fft2(double(image));
            img_f=fftshift(img_f); 
            
            m_mid=floor(M/2);
            n_mid=floor(N/2);  
            
            h = zeros(M,N);
            for i = 1:M
                for j = 1:N
                    d = ((i-m_mid)^2+(j-n_mid)^2);
                    h(i,j) = exp(-(d)/(2*(d0^2)));      
                end
            end
            img_lpf = h.*img_f; 
            img_lpf=ifftshift(img_lpf); 
            img_lpf=uint8(real(ifft2(img_lpf))); 
            PHOTO=img_lpf;
            
            axes( app.profile );
            imshow(PHOTO);
            h=msgbox('Lowpass','Filter','help');
            pause(0.5);
            close(h);
            
        end

        % Button pushed function: sobel
        function sobelButtonPushed(app, event)
            global PHOTO_Edge PHOTO_Edge1;
            axes( app.edge); 
            imshow(edge(PHOTO_Edge,'sobel',0.1));
            axes( app.edge1); 
            imshow(edge(PHOTO_Edge1,'sobel',0.1));
        end

        % Button pushed function: canny
        function cannyButtonPushed(app, event)
            global PHOTO_Edge PHOTO_Edge1;
            axes( app.edge); 
            imshow(edge(PHOTO_Edge,'canny',0.1));
            axes( app.edge1); 
            imshow(edge(PHOTO_Edge1,'canny',0.1));
        end

        % Callback function
        function logButtonPushed(app, event)
            global PHOTO_Edge PHOTO_Edge1;
            axes( app.edge); 
            imshow(edge(PHOTO_Edge,'log'));
            axes( app.edge1); 
            imshow(edge(PHOTO_Edge1,'log'));
        end

        % Callback function
        function robertButtonPushed(app, event)
            global PHOTO_Edge PHOTO_Edge1;
            axes( app.edge); 
            imshow(edge(PHOTO_Edge,'roberts',0.1));
            axes( app.edge1); 
            imshow(edge(PHOTO_Edge1,'roberts',0.1));
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            global PHOTO;
            [FileName,PathName] = uiputfile({'*.jpg','JPEG(*.jpg)'},...
                                 'Save Picture','Untitled');
            if FileName==0
                return;
            else               
                imwrite(PHOTO,[PathName,FileName]);
                h=msgbox('Saved','Info','help');
                pause(0.5);
                close(h);
            end
   
        end

        % Button pushed function: sharpen
        function sharpenButtonPushed(app, event)
            global PHOTO;
            % Laplacian sharpen
            kernel = [-1 -1 -1 ; -1 8 -1 ; -1 -1 -1];
            H_F=PHOTO
            H_F   = conv2(H_F,kernel,'same');
            PHOTO = uint8(H_F) + PHOTO; %merge the image with high frequence part
            axes(app.profile);
            imshow(PHOTO);
            h=msgbox('Sharpened','Filter','help');
            pause(0.5);
            close(h);
        end

        % Button pushed function: thresholding
        function thresholdingButtonPushed(app, event)
            global PHOTO_Edge PHOTO_Edge1;
            PHOTO_Tresh=PHOTO_Edge;
            PHOTO_Tresh1=PHOTO_Edge1;
            T = graythresh(PHOTO_Tresh)*255;
            T1 = graythresh(PHOTO_Tresh1)*255;
            PHOTO_Tresh = PHOTO_Tresh>T;
            PHOTO_Tresh1 = PHOTO_Tresh1>T1;
            axes( app.edge); 
            imshow(PHOTO_Tresh);
            axes( app.edge1); 
            imshow(PHOTO_Tresh1);
        end

        % Button pushed function: restoration
        function restorationBottonPushed(app, event)
            global PHOTO;
            f = PHOTO;
            h = fspecial('motion');
            fhat = deconvwnr(f,h,.01);
            PHOTO = fhat;
            axes(app.profile);
            imshow(PHOTO)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.screen = uifigure('Visible', 'off');
            app.screen.Name = 'com.neu.faceRec';
            app.screen.Tag = 'figure1';
            app.hist = uiaxes(app.screen);
            app.hist.XTick = [];
            app.hist.YTick = [];
            app.hist.Tag = 'hist';
            app.histE = uiaxes(app.screen);
            app.histE.XTick = [];
            app.histE.YTick = [];
            app.histE.Tag = 'histE';
            app.edge1 = uiaxes(app.screen);
            app.edge1.XTick = [];
            app.edge1.YTick = [];
            app.edge1.Tag = 'edge';
            app.edge = uiaxes(app.screen);
            app.edge.XLim = [0 1];
            app.edge.XAxisLocation = 'origin';
            app.edge.XTick = [];
            app.edge.YTick = [];
            app.edge.Tag = 'edge';
            app.uibuttongroup3 = uibuttongroup(app.screen);
            app.uibuttongroup3.Title = 'LOAD';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.load = uibutton(app.uibuttongroup3, 'push');
            app.load.ButtonPushedFcn = createCallbackFcn(app, @load_Callback, true);
            app.load.Tag = 'Train';
            app.load.Text = 'Load';
            app.chooseButton = uibutton(app.uibuttongroup3, 'push');
            app.chooseButton.ButtonPushedFcn = createCallbackFcn(app, @chooseButton_Callback, true);
            app.chooseButton.Tag = 'chooseButton';
            app.chooseButton.Text = 'Choose';
            app.match = uibutton(app.uibuttongroup3, 'push');
            app.match.ButtonPushedFcn = createCallbackFcn(app, @match_Callback, true);
            app.match.Tag = 'match';
            app.match.Enable = 'off';
            app.match.Text = 'Match';
            app.Upload = uihyperlink(app.screen);
            app.Upload.URL = 'http://sandmanos.pythonanywhere.com/';
            app.Upload.Text = 'Remotely Upload';
            app.ENHANCEPanel = uipanel(app.screen);
            app.ENHANCEPanel.Title = 'ENHANCE';
            app.lowpass = uibutton(app.ENHANCEPanel, 'push');
            app.lowpass.ButtonPushedFcn = createCallbackFcn(app, @lowpassButtonPushed, true);
            app.lowpass.Tag = 'lowpass';
            app.lowpass.Enable = 'off';
            app.lowpass.Text = 'Gaus. De-noise';
            app.sharpen = uibutton(app.ENHANCEPanel, 'push');
            app.sharpen.ButtonPushedFcn = createCallbackFcn(app, @sharpenButtonPushed, true);
            app.sharpen.Tag = 'sharpen';
            app.sharpen.Enable = 'off';
            app.sharpen.Text = 'Sharpen';
            app.smooth = uibutton(app.ENHANCEPanel, 'push');
            app.smooth.ButtonPushedFcn = createCallbackFcn(app, @smoothButtonPushed, true);
            app.smooth.Tag = 'smooth';
            app.smooth.Enable = 'off';
            app.smooth.Text = 'S&P De-noise ';
            app.histeq = uibutton(app.ENHANCEPanel, 'push');
            app.histeq.ButtonPushedFcn = createCallbackFcn(app, @histeqButtonPushed, true);
            app.histeq.Tag = 'histeq';
            app.histeq.Enable = 'off';
            app.histeq.Text = 'Hist equal';
            app.TOLPanel = uipanel(app.screen);
            app.TOLPanel.Title = 'TOL';
            app.variance = uibutton(app.TOLPanel, 'push');
            app.variance.ButtonPushedFcn = createCallbackFcn(app, @varianceButtonPushed, true);
            app.variance.Tag = 'med';
            app.variance.Enable = 'off';
            app.variance.Text = 'Variance';
            app.CAMERAPanel = uipanel(app.screen);
            app.CAMERAPanel.Title = 'CAMERA';
            app.camera = uibutton(app.CAMERAPanel, 'push');
            app.camera.ButtonPushedFcn = createCallbackFcn(app, @cameraPushed, true);
            app.camera.Text = 'Camera';
            app.spot = uibutton(app.CAMERAPanel, 'push');
            app.spot.ButtonPushedFcn = createCallbackFcn(app, @spotPushed, true);
            app.spot.Tag = 'spot';
            app.spot.Enable = 'off';
            app.spot.Text = 'Spot';
            app.close = uibutton(app.CAMERAPanel, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closePushed, true);
            app.close.Tag = 'close';
            app.close.Enable = 'off';
            app.close.Text = 'Close';
            app.uibuttongroup3_2 = uibuttongroup(app.screen);
            app.uibuttongroup3_2.Title = 'GUEST';
            app.uibuttongroup3_2.Tag = 'uibuttongroup3';
            app.profile = uiaxes(app.uibuttongroup3_2);
            app.profile.XTick = [];
            app.profile.YTick = [];
            app.profile.Tag = 'profile';
            app.uibuttongroup3_3 = uibuttongroup(app.screen);
            app.uibuttongroup3_3.Title = 'RESULT';
            app.uibuttongroup3_3.Tag = 'uibuttongroup3';
            app.result = uiaxes(app.uibuttongroup3_3);
            app.result.XTick = [];
            app.result.YTick = [];
            app.result.Tag = 'result';
            app.EdgePanel = uipanel(app.screen);
            app.EdgePanel.Title = 'EDGE';
            app.canny = uibutton(app.EdgePanel, 'push');
            app.canny.ButtonPushedFcn = createCallbackFcn(app, @cannyButtonPushed, true);
            app.canny.Tag = 'canny';
            app.canny.Enable = 'off';
            app.canny.Text = 'Best Perf.';
            app.sobel = uibutton(app.EdgePanel, 'push');
            app.sobel.ButtonPushedFcn = createCallbackFcn(app, @sobelButtonPushed, true);
            app.sobel.Tag = 'sobel';
            app.sobel.Enable = 'off';
            app.sobel.Text = 'Fast Speed';
            app.save = uibutton(app.screen, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Tag = 'save';
            app.save.Enable = 'off';
            app.save.Text = 'Save';
            app.ThresholdingPanel = uipanel(app.screen);
            app.ThresholdingPanel.Title = 'THRESHOLD';
            app.thresholding = uibutton(app.ThresholdingPanel, 'push');
            app.thresholding.ButtonPushedFcn = createCallbackFcn(app, @thresholdingButtonPushed, true);
            app.thresholding.Tag = 'thresholding';
            app.thresholding.Enable = 'off';
            app.thresholding.Text = 'Thresholding';
            app.RestorationPanel = uipanel(app.screen);
            app.RestorationPanel.Title = 'RESTORATION';
            app.restoration = uibutton(app.RestorationPanel, 'push');
            app.restoration.ButtonPushedFcn = createCallbackFcn(app, @restorationBottonPushed, true);
            app.restoration.Tag = 'sobel';
            app.restoration.Enable = 'off';
            app.restoration.Text = 'De-Blur';
            % Show the figure after all components are created
            app.screen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Face_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.screen)

                % Execute the startup function
                runStartupFcn(app, @(app)face_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.screen)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.screen)
        end
    end
end